classdef Cobblefinder < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        CobbleLoggedLabel          matlab.ui.control.Label
        LogCobbleButton            matlab.ui.control.Button
        TabGroup                   matlab.ui.container.TabGroup
        ControlsTab                matlab.ui.container.Tab
        GridLayout2                matlab.ui.container.GridLayout
        ContinuoutModeSwitch       matlab.ui.control.Switch
        ContinuoutModeSwitchLabel  matlab.ui.control.Label
        RSSIGauge                  matlab.ui.control.LinearGauge
        RSSIGaugeLabel             matlab.ui.control.Label
        TagIDLabel                 matlab.ui.control.Label
        PrevioustagButton          matlab.ui.control.Button
        NexttagButton              matlab.ui.control.Button
        UIAxes4                    matlab.ui.control.UIAxes
        DatalogTab                 matlab.ui.container.Tab
        GridLayout3                matlab.ui.container.GridLayout
        UITable                    matlab.ui.control.Table
        TESTINGTab                 matlab.ui.container.Tab
        GridLayout6                matlab.ui.container.GridLayout
        WalkSwitch                 matlab.ui.control.Switch
        WalkSwitchLabel            matlab.ui.control.Label
        HeadingKnob                matlab.ui.control.Knob
        HeadingKnobLabel           matlab.ui.control.Label
        ConfigureTab               matlab.ui.container.Tab
        GridLayout4                matlab.ui.container.GridLayout
        GridLayout5                matlab.ui.container.GridLayout
        ZoommDropDownLabel         matlab.ui.control.Label
        ZoommDropDown              matlab.ui.control.DropDown
        SnailtrailsCheckBox        matlab.ui.control.CheckBox
        MoplinesCheckBox           matlab.ui.control.CheckBox
        GPSListBox                 matlab.ui.control.ListBox
        GPSSwitch                  matlab.ui.control.Switch
        GPSSwitchLabel             matlab.ui.control.Label
        GPSLamp                    matlab.ui.control.Lamp
        GPSCOMListBox              matlab.ui.control.ListBox
        GPSCOMSwitch               matlab.ui.control.Switch
        GPSCOMSwitchLabel          matlab.ui.control.Label
        GPSCOMLamp                 matlab.ui.control.Lamp
        AntennaCOMListBox          matlab.ui.control.ListBox
        AntennaCOMSwitch           matlab.ui.control.Switch
        AntennaCOMSwitchLabel      matlab.ui.control.Label
        AntennaCOMLamp             matlab.ui.control.Lamp
        UIAxes3                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        % App Variables
        GPScord
        MOPlat
        MOPlon
        snail
        heading
        uihold
        GPSloc1 %test plot info for gps cord
        GPSloc2
        GPSloc3

        DSPsnail % (Boolean) -Display "snail trail"
        DSPmop % (Boolean) -Display MOP lines
        mapoffset %There must be a more elegant solution to map zoom level
        % maybe do some math to figure out a scale bar
        plotmode % may become unecessary because geoplot mode not working with Uiaxes
        
        % timers
        gpstmr
        wlktmr
    end
    
    methods (Access = private)
        function GPSplot(app, src, ~, ~) %(app, obj, event, string_arg)
%             app.uihold=true;
            tic
%             uiwait(app.UIAxes3)
            cla(app.UIAxes3)
        
            % Optional arguments (varargin)

            % 'mop' - display mop lines
            if app.DSPmop
                switch app.plotmode
                    case 'g'
                        hold(app.UIAxes3,'on')
                        geoplot(app.UIAxes3,app.MOPlat,app.MOPlon,'red')
                    case 'p'
                        hold(app.UIAxes3,'on')
                        plot(app.UIAxes3,app.MOPlon,app.MOPlat,'red')
                end
            end

            % 'snail' - display snail trails (previous GPS line)
            if app.DSPsnail
                app.snail(end+1,:)=app.GPScord;
                 switch app.plotmode
                    case 'g'
                        geoplot(app.UIAxes3,app.snail(:,1),app.snail(:,2),'green')
                    case 'p'
                        plot(app.UIAxes3,app.snail(:,2),app.snail(:,1),'green')
                 end
                 hold(app.UIAxes3,'on')
            else
                hold(app.UIAxes3,'off')
            end
        
            
            % Main GPS pos map

            % calc map limits
            lat=[app.GPScord(1)-app.mapoffset,app.GPScord(1)+app.mapoffset];
            lon=[app.GPScord(2)-app.mapoffset,app.GPScord(2)+app.mapoffset];
%             fprintf('lat: %d %d\n',lat)
%             fprintf('lon: %d %d\n',lon)
        
            % Plot map
            switch app.plotmode
                case 'g' % Geomap
                    hold(app.UIAxes3,'on')
                    geoscatter(app.UIAxes3,app.GPScord(1),app.GPScord(2),150,'blue','x','LineWidth',1);
                    geoscatter(app.UIAxes3,app.GPScord(1),app.GPScord(2),250,'blue','o','LineWidth',1);
            
                    geolimits(lat,lon)
                    geobasemap topographic
                case 'p' % fast plot
                    hold(app.UIAxes3,'on')
                    scatter(app.UIAxes3,app.GPScord(2),app.GPScord(1),150,'blue','x','LineWidth',1);
                    scatter(app.UIAxes3,app.GPScord(2),app.GPScord(1),250,'blue','o','LineWidth',1);
            
                    ylim(app.UIAxes3,lat)
                    xlim(app.UIAxes3,lon)
            end

        hold(app.UIAxes3,'off')
%         uiresume(app.UIAxes3)
%         app.uihold=false;
%         pause(0.001) %appears to hang easier

        drawnow
        toc
        end

        
        function GPSupdate(app,src,~,~)
            tic

            % 'snail' - display snail trails (previous GPS line)
            if app.DSPsnail
                app.snail(end+1,:)=app.GPScord;
                set(app.GPSloc3,'XData',app.snail(:,2),'YData',app.snail(:,1))
            end

            % Set map limits
            lat=[app.GPScord(1)-app.mapoffset,app.GPScord(1)+app.mapoffset];
            lon=[app.GPScord(2)-app.mapoffset,app.GPScord(2)+app.mapoffset];
            ylim(app.UIAxes3,lat)
            xlim(app.UIAxes3,lon)

            % Plot GPS cord
            set(app.GPSloc1,'XData',app.GPScord(2),'YData',app.GPScord(1))
            set(app.GPSloc2,'XData',app.GPScord(2),'YData',app.GPScord(1))

            % Enable GPS lamp
            taskEx = get(src,'TasksExecuted');
            if mod(taskEx,2)
                app.GPSLamp.Enable='on';
                app.GPSLamp.Color='g';
            else
                app.GPSLamp.Enable='off';
                app.GPSLamp.Color='r';
            end

            drawnow limitrate;
%             pause(0.001)
            toc
        end


        %% walking test
        function walktest(app,~,~,~)
%             app.uihold=true;
%             uiwait(app.UIFigure)
            walkspeed=5.3666e-07;
%             fprintf('GPS cord: %d + walking speed: %d = %d\n',app.GPScord(1),walkspeed*cosd(app.heading),app.GPScord(1)-walkspeed*cosd(app.heading))
%             disp(now)
            app.GPScord(1)=app.GPScord(1)-(walkspeed*cosd(app.heading));
            app.GPScord(2)=app.GPScord(2)-(walkspeed*sind(app.heading));
%             app.uihold=false;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %% Auto adjust GUI size to fit screen resolution 
            % https://github.com/dseyit/Matlab-App-Designer---Screen-UI-auto-resize.git

            %% Layout parameters
            app.UIFigure.WindowState='maximized'
            app.UIFigure.Units="normalized"

            %% Startup Code
            
            app.DSPsnail=app.SnailtrailsCheckBox.Value;
            app.DSPmop=app.MoplinesCheckBox.Value;
%             app.mappoffset=app.ZoommDropDown.Value
            app.mapoffset=0.001; %Temp
            app.plotmode='p';

            app.heading = app.HeadingKnob.Value;


            % _____ RSSI _____

            COM="COM4";
            addpath(genpath('C:\Users\ccblack\Documents\Carson\IOD_activeRFID')) %flag hide this line when compiling, make sure all files are included in compiler options
            pause(0.05)

            % Import tag list
            [fid, msg]=fopen('taglist.txt','r');
            if fid < 0
                error('Failed to open file "%s" because: "%s"', filename, msg);
            end
            C = textscan(fid,'%s');
            tagIDlist = hex2dec(C{1});
            fclose(fid);
            num_tags=length(tagIDlist);
            clear C fid ans
            
            % Configure Serial
            if any(strcmp(serialportlist('all'),COM))
                Antenna=serialport(COM,9600);
                configureTerminator(Antenna,93); % 93 ascii = ']'
                % Antenna.BytesAvailableFcn
                % serialportlist('all')
            else
                error('Antenna COM port not available (%s)')
            end


            % _____ KMl _____
            [app.MOPlon,app.MOPlat]=MOPelementreducer('MOPs_SD_County.kml');
            
            app.GPScord=[32.927981236100734, -117.25985543852305]; %[Lat, Lon]
            
            app.snail=app.GPScord;

            %% Startup timers
            %GPS timer
            switch app.plotmode
                case 'g'
                    app.gpstmr=timer('ExecutionMode', 'FixedRate', 'Period', 0.5, ...
                        'TimerFcn', "GPSplot('','',0.001,'g','mop','snail');", ...
                        'StopFcn',"disp('GPS timer stopped')",'ErrorFcn',"disp('GPS erorr!')")
                case 'p'
                    app.gpstmr=timer('ExecutionMode', 'FixedRate', 'Period', 0.24, ...
                        'TimerFcn', {@app.GPSupdate,app}, ...
                        'StopFcn',"disp('GPS timer stopped')",'ErrorFcn',"disp('GPS erorr!')") %flag NOT WORKING
            end
            start(app.gpstmr)

            %Walk timer %test
            app.wlktmr=timer('ExecutionMode', 'FixedRate', 'Period', 0.05, ...
                        'TimerFcn', {@app.walktest,app}, ...
                        'StopFcn',"disp('Walktmr stopped')",'ErrorFcn',"disp('Walktmr erorr!')");

            %% %test
            app.GPSloc1= scatter(app.UIAxes3,app.GPScord(2),app.GPScord(1),150,'blue','x','LineWidth',1);
            hold(app.UIAxes3,'on')
            app.GPSloc2= scatter(app.UIAxes3,app.GPScord(2),app.GPScord(1),150,'blue','o','LineWidth',1);

            % 'mop' - display mop lines
            if app.DSPmop
                plot(app.UIAxes3,app.MOPlon,app.MOPlat,'red')
            end

            % 'snail' - display snail trails (previous GPS line)
            if app.DSPsnail
                app.snail(end+1,:)=app.GPScord;
                app.GPSloc3=plot(app.UIAxes3,app.snail(:,2),app.snail(:,1),'green');
            end
        end

        % Value changed function: ContinuoutModeSwitch
        function ContinuoutModeSwitchValueChanged(app, event)
%             compare='On';
%             TF = strcmp(value,compare) %convert value to logical
%             while TF==true
%                 if value==false
%                     break
%                 end
%                 disp('working')
%                 pause(1)
%             end

%% Better method: use a switch and cases to createa a timer function
            switch app.ContinuoutModeSwitch.Value
                case 'On'
%                     t=timer('ExecutionMode', 'FixedRate', ...
%     'Period', 1, ...
%     'TimerFcn', {@CallbackTimer});
                case 'off'
%                     delete(t)
            end
        end

        % Value changed function: GPSSwitch
        function GPSSwitchValueChanged(app, event)
            value = app.GPSSwitch.Value;
            switch value
                case 'On'
                    start(app.gpstmr)
                    app.GPSLamp.Enable='on';
                case 'Off'
                    stop(app.gpstmr)
                    app.GPSLamp.Enable='off';
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(timerfind) % stop all timers
            delete(timerfind) % delete all timers
            delete(app)
        end

        % Value changed function: WalkSwitch
        function WalkSwitchValueChanged(app, event)
            value = app.WalkSwitch.Value
            switch value
                case 'On'
                    start(app.wlktmr)
                case 'Off'
                    stop(app.wlktmr)
            end
        end

        % Value changed function: HeadingKnob
        function HeadingKnobValueChanged(app, event)
            app.heading = app.HeadingKnob.Value;
        end

        % Value changed function: SnailtrailsCheckBox
        function SnailtrailsCheckBoxValueChanged(app, event)
            app.DSPsnail=app.SnailtrailsCheckBox.Value;            
        end

        % Value changed function: MoplinesCheckBox
        function MoplinesCheckBoxValueChanged(app, event)
            app.DSPmop=app.MoplinesCheckBox.Value;
        end

        % Value changing function: HeadingKnob
        function HeadingKnobValueChanging(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes3 = uiaxes(app.GridLayout);
            title(app.UIAxes3, 'GPS')
            xlabel(app.UIAxes3, 'Longitude')
            ylabel(app.UIAxes3, 'Latitude')
            zlabel(app.UIAxes3, 'Z')
            app.TabGroup = uitabgroup(app.GridLayout);
            app.ControlsTab = uitab(app.TabGroup);
            app.ControlsTab.Title = 'Controls';
            app.GridLayout2 = uigridlayout(app.ControlsTab);
            app.UIAxes4 = uiaxes(app.GridLayout2);
            title(app.UIAxes4, 'RSSI')
            xlabel(app.UIAxes4, 'Tag ID')
            ylabel(app.UIAxes4, 'Signal Strength')
            zlabel(app.UIAxes4, 'Z')
            app.NexttagButton = uibutton(app.GridLayout2, 'push');
            app.NexttagButton.Text = 'Next tag';
            app.PrevioustagButton = uibutton(app.GridLayout2, 'push');
            app.PrevioustagButton.Text = 'Previous tag';
            app.TagIDLabel = uilabel(app.GridLayout2);
            app.TagIDLabel.Text = 'Tag ID: ';
            app.RSSIGaugeLabel = uilabel(app.GridLayout2);
            app.RSSIGaugeLabel.Text = 'RSSI';
            app.RSSIGauge = uigauge(app.GridLayout2, 'linear');
            app.ContinuoutModeSwitchLabel = uilabel(app.GridLayout2);
            app.ContinuoutModeSwitchLabel.Text = 'Continuout Mode';
            app.ContinuoutModeSwitch = uiswitch(app.GridLayout2, 'slider');
            app.ContinuoutModeSwitch.ValueChangedFcn = createCallbackFcn(app, @ContinuoutModeSwitchValueChanged, true);
            app.DatalogTab = uitab(app.TabGroup);
            app.DatalogTab.Title = 'Datalog';
            app.GridLayout3 = uigridlayout(app.DatalogTab);
            app.UITable = uitable(app.GridLayout3);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TESTINGTab = uitab(app.TabGroup);
            app.TESTINGTab.Title = 'TESTING';
            app.GridLayout6 = uigridlayout(app.TESTINGTab);
            app.HeadingKnobLabel = uilabel(app.GridLayout6);
            app.HeadingKnobLabel.Text = 'Heading';
            app.HeadingKnob = uiknob(app.GridLayout6, 'continuous');
            app.HeadingKnob.Limits = [0 360];
            app.HeadingKnob.ValueChangedFcn = createCallbackFcn(app, @HeadingKnobValueChanged, true);
            app.HeadingKnob.ValueChangingFcn = createCallbackFcn(app, @HeadingKnobValueChanging, true);
            app.HeadingKnob.Value = 180;
            app.WalkSwitchLabel = uilabel(app.GridLayout6);
            app.WalkSwitchLabel.Text = 'Walk';
            app.WalkSwitch = uiswitch(app.GridLayout6, 'slider');
            app.WalkSwitch.ValueChangedFcn = createCallbackFcn(app, @WalkSwitchValueChanged, true);
            app.ConfigureTab = uitab(app.TabGroup);
            app.ConfigureTab.Title = 'Configure';
            app.GridLayout4 = uigridlayout(app.ConfigureTab);
            app.AntennaCOMLamp = uilamp(app.GridLayout4);
            app.AntennaCOMSwitchLabel = uilabel(app.GridLayout4);
            app.AntennaCOMSwitchLabel.Text = 'Antenna COM';
            app.AntennaCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.AntennaCOMSwitch.Value = 'On';
            app.AntennaCOMListBox = uilistbox(app.GridLayout4);
            app.AntennaCOMListBox.Enable = 'off';
            app.GPSCOMLamp = uilamp(app.GridLayout4);
            app.GPSCOMSwitchLabel = uilabel(app.GridLayout4);
            app.GPSCOMSwitchLabel.Text = 'GPS COM';
            app.GPSCOMSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GPSCOMSwitch.Value = 'On';
            app.GPSCOMListBox = uilistbox(app.GridLayout4);
            app.GPSCOMListBox.Enable = 'off';
            app.GPSLamp = uilamp(app.GridLayout4);
            app.GPSSwitchLabel = uilabel(app.GridLayout4);
            app.GPSSwitchLabel.Text = 'GPS Map';
            app.GPSSwitch = uiswitch(app.GridLayout4, 'slider');
            app.GPSSwitch.ValueChangedFcn = createCallbackFcn(app, @GPSSwitchValueChanged, true);
            app.GPSSwitch.Value = 'On';
            app.GPSListBox = uilistbox(app.GridLayout4);
            app.GPSListBox.Items = {'G mode', 'P mode', ''};
            app.GPSListBox.Enable = 'off';
            app.GPSListBox.Value = 'P mode';
            app.GridLayout5 = uigridlayout(app.GridLayout4);
            app.MoplinesCheckBox = uicheckbox(app.GridLayout5);
            app.MoplinesCheckBox.ValueChangedFcn = createCallbackFcn(app, @MoplinesCheckBoxValueChanged, true);
            app.MoplinesCheckBox.Text = 'Mop lines';
            app.MoplinesCheckBox.Value = true;
            app.SnailtrailsCheckBox = uicheckbox(app.GridLayout5);
            app.SnailtrailsCheckBox.ValueChangedFcn = createCallbackFcn(app, @SnailtrailsCheckBoxValueChanged, true);
            app.SnailtrailsCheckBox.Text = 'Snail trails';
            app.SnailtrailsCheckBox.Value = true;
            app.ZoommDropDown = uidropdown(app.GridLayout5);
            app.ZoommDropDown.Items = {'50', '100', '200', '500', '750', '1000'};
            app.ZoommDropDown.Value = '50';
            app.ZoommDropDownLabel = uilabel(app.GridLayout5);
            app.ZoommDropDownLabel.Text = 'Zoom (m)';
            app.LogCobbleButton = uibutton(app.GridLayout, 'push');
            app.LogCobbleButton.Text = 'Log Cobble';
            app.CobbleLoggedLabel = uilabel(app.GridLayout);
            app.CobbleLoggedLabel.Text = 'Cobble Logged!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cobblefinder

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end